classdef frequencyresponse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EndfrequenzEditField            matlab.ui.control.NumericEditField
        EndfrequenzHzEditFieldLabel_2   matlab.ui.control.Label
        StartfrequenzEditField          matlab.ui.control.NumericEditField
        StartfrequenzHzEditFieldLabel_2  matlab.ui.control.Label
        KapazitaetEditField             matlab.ui.control.NumericEditField
        KapazittCFEditFieldLabel_2      matlab.ui.control.Label
        InduktivitaetEditField          matlab.ui.control.NumericEditField
        InduktivittLmHEditFieldLabel_2  matlab.ui.control.Label
        WiderstandEditField             matlab.ui.control.NumericEditField
        WiderstandREditFieldLabel_2     matlab.ui.control.Label
        SprachenButton                  matlab.ui.control.StateButton
        BerechnenButton                 matlab.ui.control.Button
        DarstellungButtonGroup          matlab.ui.container.ButtonGroup
        LeitwertButton                  matlab.ui.control.RadioButton
        WiderstandButton                matlab.ui.control.RadioButton
        SchaltungsImage                 matlab.ui.control.Image
        SchaltungDropDown               matlab.ui.control.DropDown
        SchaltungDropDownLabel          matlab.ui.control.Label
        HilfeButton                     matlab.ui.control.Button
        FrequenzantwortLabel            matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Title=[{'Frequenzantwort'}; {'Frequenzy Response'}];
        AxesTitle=[{'Nyquistdiagramm'}; {'Nyquist Diagram'}];
        Circuit=[{'Schaltung'} {'Circuit'}];
        CircuitItems=[{'Reihenschaltung', 'Serial Connection'};...
            {'Parallelschaltung', 'Parallel Connection'}]
        CircuitValue=[{'Reihenschaltung'} {'serial connection'}];
        GroupTitle=[{'Darstellung'} {'Depiction'}];
        WiderstandBtn=[{'Widerstand'} {'Resistance'}];
        LeitwertBtn=[{'Leitwert'} {'Conductance'}];
        WiderstandFld=[{'Widerstand R [Ω]'} {'Resitance R [Ω]'}];
        InduktivitaetFld=[{'Induktivität L [mH]'} {'Inductance L [mH]'}];
        KapazitaetFld=[{'Kapazität C [µF]'} {'Capacity C [µF]'}];
        StartfreqFld=[{'Startfrequenz [Hz]'} {'Start Frequency [Hz]'}];
        EndfreqFld=[{'Endfrequenz [Hz]'} {'End Frequenzy [Hz]'}];
        CalcBtn=[{'Berechnen'} {'Calculate'}];
        Language=[{'EN'} {'DE'}];
        HelpText=["Dieses Programm berechnet die Ortskurve" + ...
        " einer R-L-C-Schaltung. Geben Sie für den Widerstand," + ...
         " die Induktivität und die Kapazität die gewünschten Werte" + ...
         " ein. Dann wählen Sie eine Anfangs- und Endfrequenz aus " + ...
         "und drücken den Berechnen-Knopf."; "This program calculates" + ...
         " the Nyquist plot of an R-L-C circuit. Enter the desired" + ...
         " values for the resistance, inductance and capacity. " + ...
        "Then select a start and end frequency and press calculate."];
        HelpTitle=["Hilfe"; "Help"];
        ResFreq=["Resonanzfrequenz "; "Resonance Frequency "];
        Freq=["Frequenz [Hz]"; "Frequency [Hz]"];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SchaltungDropDown
        function SchaltungDropDownValueChanged(app, event)
            value = app.SchaltungDropDown.Value;
            if strcmp(value, app.CircuitItems(1, app.SprachenButton.Value+1))
                app.SchaltungsImage.ImageSource = 'Reihenschaltung.png';
            elseif strcmp(value, app.CircuitItems(2, app.SprachenButton.Value+1))
                app.SchaltungsImage.ImageSource = 'Parallelschaltung.png';
            end

        end

        % Value changed function: StartfrequenzEditField
        function StartfrequenzEditFieldValueChanged(app, event)
            value = app.StartfrequenzEditField.Value;
            app.EndfrequenzEditField.Limits = [value inf];
        end

        % Value changed function: EndfrequenzEditField
        function EndfrequenzEditFieldValueChanged(app, event)
            value = app.EndfrequenzEditField.Value;
            app.StartfrequenzEditField.Limits = [0 value];
        end

        % Value changed function: SprachenButton
        function SprachenButtonValueChanged(app, event)
            lang = app.SprachenButton.Value+1;
            title(app.UIAxes, app.AxesTitle(lang));
            app.DarstellungButtonGroup.Title = app.GroupTitle(lang);
            app.WiderstandButton.Text = app.WiderstandBtn(lang);
            app.LeitwertButton.Text = app.LeitwertBtn(lang);
            app.SchaltungDropDownLabel.Text = app.Circuit(lang);
            app.FrequenzantwortLabel.Text = app.Title(lang);
            app.WiderstandREditFieldLabel_2.Text= app.WiderstandFld(lang);
            app.InduktivittLmHEditFieldLabel_2.Text = app.InduktivitaetFld(lang);
            app.KapazittCFEditFieldLabel_2.Text = app.KapazitaetFld(lang);
            app.StartfrequenzHzEditFieldLabel_2.Text = app.StartfreqFld(lang);
            app.EndfrequenzHzEditFieldLabel_2.Text = app.EndfreqFld(lang);
            app.BerechnenButton.Text = app.CalcBtn(lang);
            app.SprachenButton.Text = app.Language(lang);
            app.SchaltungDropDown.Items = app.CircuitItems(:, lang);
            app.SchaltungDropDown.Value = app.CircuitValue(lang);
            SchaltungDropDownValueChanged(app, event);
            cla(app.UIAxes);
        end

        % Button pushed function: HilfeButton
        function HilfeButtonPushed(app, event)
            helptext=app.HelpText(app.SprachenButton.Value+1);
            helptitle=app.HelpTitle(app.SprachenButton.Value+1);
            msgbox(helptext, helptitle);
        end

        % Button pushed function: BerechnenButton
        function BerechnenButtonPushed(app, event)
            %Löscht den aktuellen Plot
            cla(app.UIAxes)
            %Liest die Werte aus den Edit Fields in Variablen
            C=app.KapazitaetEditField.Value*1e-6;
            L=app.InduktivitaetEditField.Value*1e-3;
            R=app.WiderstandEditField.Value;
            %Erstellen einer logarithmischen Frequenzachse
            omega=2*pi*logspace(log10(app.StartfrequenzEditField.Value), ...
            log10(app.EndfrequenzEditField.Value), 20000);
            %Berechnen der Resonanzfrequenz
            omega_R=1/(sqrt(L*C));
            %Abfrage ob man in Reihen oder Parallelschaltung ist mit jeweiliger Berechnung
            if strcmp(app.SchaltungDropDown.Value,...
                app.CircuitItems(1, app.SprachenButton.Value+1))
                Z=R+1i*omega*L+1./(1i*omega*C);
            else
                Z=(1./R+1./(1i.*omega.*L)+1i.*omega.*C).^-1;
            end
            Y=1./Z;
            Zr=R+1i*omega_R*L+1./(1i*omega_R*C);
            Yr=1/Zr;
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            %Darstellung Resonanzfrequenz
            frtext=[app.ResFreq(app.SprachenButton.Value+1,:)+...
             num2str(omega_R/(2*pi), '%.3f')+'Hz \rightarrow'];
            %Abfrage ob Widerstand oder Leitwert dargestellt werden soll
            if app.DarstellungButtonGroup.SelectedObject==app.LeitwertButton
                fig=plot(app.UIAxes, real(Y), imag(Y));
                plot(app.UIAxes, real(Yr), imag(Yr), 'X');
                %Achsenbeschriftung Leitwert
                
                xlabel(app.UIAxes, 'Re(Y) [S]');
                ylabel(app.UIAxes, 'Im(Y) [S]');
                text(app.UIAxes, real(Yr), imag(Yr), frtext, 'HorizontalAlignment', 'right')
            else
                fig=plot(app.UIAxes, real(Z), imag(Z));
                if omega_R<app.EndfrequenzEditField.Value && omega_R> ...
                    app.StartfrequenzEditField.Value
                    plot(app.UIAxes, real(Zr), imag(Zr), 'X');
                    text(app.UIAxes, real(Zr), imag(Zr), frtext, 'HorizontalAlignment', 'right')
                end
                %Achsenbeschriftung Widerstand
                xlabel(app.UIAxes, 'Re(Z) [Ω]');
                ylabel(app.UIAxes, 'Im(Z) [Ω]');
            end
            %Data Tooltip Erweiterung so dass die Frequenzen mit angezeigt werden
            row = dataTipTextRow(app.Freq(app.SprachenButton.Value+1,:),omega/(2*pi));
            fig.DataTipTemplate.DataTipRows(end+1) = row;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Nyquistdiagramm')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FrequenzantwortLabel = uilabel(app.UIFigure);
            app.FrequenzantwortLabel.Text = 'Frequenzantwort';
            app.HilfeButton = uibutton(app.UIFigure, 'push');
            app.HilfeButton.ButtonPushedFcn = createCallbackFcn(app, @HilfeButtonPushed, true);
            app.HilfeButton.Text = '?';
            app.SchaltungDropDownLabel = uilabel(app.UIFigure);
            app.SchaltungDropDownLabel.Text = 'Schaltung';
            app.SchaltungDropDown = uidropdown(app.UIFigure);
            app.SchaltungDropDown.Items = {'Reihenschaltung', 'Parallelschaltung'};
            app.SchaltungDropDown.ValueChangedFcn = createCallbackFcn(app, @SchaltungDropDownValueChanged, true);
            app.SchaltungDropDown.Value = 'Reihenschaltung';
            app.SchaltungsImage = uiimage(app.UIFigure);
            app.SchaltungsImage.ImageSource = fullfile(pathToMLAPP, 'Reihenschaltung.png');
            app.DarstellungButtonGroup = uibuttongroup(app.UIFigure);
            app.DarstellungButtonGroup.Title = 'Darstellung';
            app.WiderstandButton = uiradiobutton(app.DarstellungButtonGroup);
            app.WiderstandButton.Text = 'Widerstand';
            app.WiderstandButton.Value = true;
            app.LeitwertButton = uiradiobutton(app.DarstellungButtonGroup);
            app.LeitwertButton.Text = 'Leitwert';
            app.BerechnenButton = uibutton(app.UIFigure, 'push');
            app.BerechnenButton.ButtonPushedFcn = createCallbackFcn(app, @BerechnenButtonPushed, true);
            app.BerechnenButton.Text = 'Berechnen';
            app.SprachenButton = uibutton(app.UIFigure, 'state');
            app.SprachenButton.ValueChangedFcn = createCallbackFcn(app, @SprachenButtonValueChanged, true);
            app.SprachenButton.Text = 'EN';
            app.WiderstandREditFieldLabel_2 = uilabel(app.UIFigure);
            app.WiderstandREditFieldLabel_2.Text = 'Widerstand R [Ω] ';
            app.WiderstandEditField = uieditfield(app.UIFigure, 'numeric');
            app.WiderstandEditField.Limits = [0 Inf];
            app.WiderstandEditField.Value = 10;
            app.InduktivittLmHEditFieldLabel_2 = uilabel(app.UIFigure);
            app.InduktivittLmHEditFieldLabel_2.Text = 'Induktivität L [mH]';
            app.InduktivitaetEditField = uieditfield(app.UIFigure, 'numeric');
            app.InduktivitaetEditField.Limits = [0 Inf];
            app.InduktivitaetEditField.Value = 100;
            app.KapazittCFEditFieldLabel_2 = uilabel(app.UIFigure);
            app.KapazittCFEditFieldLabel_2.Text = 'Kapazität C [µF]';
            app.KapazitaetEditField = uieditfield(app.UIFigure, 'numeric');
            app.KapazitaetEditField.Limits = [0 Inf];
            app.KapazitaetEditField.Value = 50;
            app.StartfrequenzHzEditFieldLabel_2 = uilabel(app.UIFigure);
            app.StartfrequenzHzEditFieldLabel_2.Text = 'Startfrequenz [Hz]';
            app.StartfrequenzEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartfrequenzEditField.Limits = [0 Inf];
            app.StartfrequenzEditField.ValueChangedFcn = createCallbackFcn(app, @StartfrequenzEditFieldValueChanged, true);
            app.StartfrequenzEditField.Value = 2;
            app.EndfrequenzHzEditFieldLabel_2 = uilabel(app.UIFigure);
            app.EndfrequenzHzEditFieldLabel_2.Text = 'Endfrequenz [Hz]';
            app.EndfrequenzEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndfrequenzEditField.Limits = [0 Inf];
            app.EndfrequenzEditField.ValueChangedFcn = createCallbackFcn(app, @EndfrequenzEditFieldValueChanged, true);
            app.EndfrequenzEditField.Value = 2500;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = frequencyresponse

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end